classdef ThacherIllusionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image                       matlab.ui.control.Image
        VideoControlPanel           matlab.ui.container.Panel
        VideoSourceDropDownLabel    matlab.ui.control.Label
        VideoSourceDropDown         matlab.ui.control.DropDown
        PlayVideoButton             matlab.ui.control.StateButton
        ReDetectObjectsButton       matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        IllusionStatusControlPanel  matlab.ui.container.Panel
        IllusionSwitchLabel         matlab.ui.control.Label
        IllusionSwitch              matlab.ui.control.RockerSwitch
        VerticalFlipSwitchLabel     matlab.ui.control.Label
        VerticalFlipSwitch          matlab.ui.control.RockerSwitch
    end

    
    properties (Access = private)
        cam; % カメラオブジェクト
        UpdateTimer;%タイマオブジェクト
        runLoop;
        XExpand;
        YExpand;
        
        %顔の位置推定機
        FaceDetector;
        
        %口の位置推定関係プロパティ
        Mouth;

        %目の位置推定関係プロパティ
        Eyes;

        EnableIllusion;% Description
        VerticalFlip;% Description
    end
    
    methods (Access = private)
        
        %------------------------------------------------------------------
        %タイマ割り込み関数　初期化関数で指定したFPSに応じて一定間隔で呼び出されます
        function mytimer_func(app, obj, event)
            
            %カメラからフレーム取得
            VideoFrame = snapshot(app.cam);
            
            %検出対象のオブジェクトポイント数がいずれか10以下になったら検出処理を行う
            %オブジェクトポイント数が10より多い場合、フレーム毎検出領域のトラッキングを行う
            if or(app.Eyes.NumPts < 10 , app.Mouth.NumPts < 10)
                VideoFrame = app.DetectProcess(VideoFrame);
            else
                VideoFrame =app.TrackingProcess(VideoFrame);
            end
            
            %FlipSwitchがOnなら画像を上下反転させる
            if strcmp(app.VerticalFlipSwitch.Value,'On')
                VideoFrame = flipud(VideoFrame);
            end
            
            % ビデオプレイヤーフレーム更新
            app.Image.ImageSource = VideoFrame;
            
        end
        %End mytimer_func--------------------------------------------------
        
        %------------------------------------------------------------------
        %物体検出関数　フレームから　顔・目・口の位置を検出し、トラッカーに割り当てます
        function [Return_VideoFrame] = DetectProcess(app,VideoFrame)
            disp('Detect')
            %グレースケール画像を生成
            GrayFrameImage = rgb2gray(VideoFrame);
            
            %顔領域をグレースケール画像で特定
            FaceBBox = app.FaceDetector.step(GrayFrameImage);
        
            %顔位置特定が成功したら
            if ~isempty(FaceBBox)
                
                %、顔領域の中から目の位置を特定
                app.Eyes.BBox = app.Eyes.Detector.step(GrayFrameImage,FaceBBox(1,:));
                %、顔領域の中から口の位置を特定
                app.Mouth.BBox = app.Mouth.Detector.step(GrayFrameImage,FaceBBox(1,:));
            
                %目の領域が特定できていたらVideoFrameに位置情報を重畳する。
                if ~isempty(app.Eyes.BBox)
                    % 特定領域のコーナ点を特定する.
                    Points = detectMinEigenFeatures(GrayFrameImage, 'ROI', app.Eyes.BBox(1, :));
                  
                    % ポイントトラッカーの初期化
                    app.Eyes = InitializeTrackObj(app,Points,GrayFrameImage,app.Eyes);

                    % Convert the box corners into the [x1 y1 x2 y2 x3 y3 x4 y4]
                    % ポリゴン描画のためのにポリゴン関数にあわせた座標に変換
                    bboxPolygon = reshape(app.Eyes.BBoxPoints', 1, []);
                
                    % 特定領域に長方形のポリゴンをフレームに重畳させる.
                    VideoFrame = insertShape(VideoFrame, 'Polygon', bboxPolygon, 'LineWidth', 3);
                
                    % 特定コーナをフレームに重畳させる.
                    VideoFrame = insertMarker(VideoFrame, app.Eyes.xyPoints, '+', 'Color', 'white');
                end
                
                
                %口の領域が特定できていたらVideoFrameに位置情報を重畳する。
                if ~isempty(app.Mouth.BBox)
                    % 特定領域のコーナ点を特定する.
                    Points = detectMinEigenFeatures(GrayFrameImage, 'ROI', app.Mouth.BBox(1, :));
                  
                    % ポイントトラッカーの初期化
                    app.Mouth = InitializeTrackObj(app,Points,GrayFrameImage,app.Mouth);

                    % Convert the box corners into the [x1 y1 x2 y2 x3 y3 x4 y4]
                    % format required by insertShape.
                    bboxPolygon = reshape(app.Mouth.BBoxPoints', 1, []);
                
                    % Display a bounding box around the detected face.
                    VideoFrame = insertShape(VideoFrame, 'Polygon', bboxPolygon, 'LineWidth', 3);
                
                    % Display detected corners.
                    VideoFrame = insertMarker(VideoFrame, app.Mouth.xyPoints, '+', 'Color', 'white');
                end

            end
            Return_VideoFrame = VideoFrame;

        end
        %End DetectProcess-------------------------------------------------

        %------------------------------------------------------------------
        %物体トラッキング関数　一つ前のフレームトラッキング結果から　
        %トラッキング対象領域座標を更新する
        function Return_VideoFrame = TrackingProcess(app,VideoFrame)

           %目のトラッカーを更新
           [VideoFrame,app.Eyes] = UpdateTrackObject(app,app.Eyes,VideoFrame);
           
           %口のトラッカーを更新
           [VideoFrame,app.Mouth] = UpdateTrackObject(app,app.Mouth,VideoFrame);
           
           Return_VideoFrame = VideoFrame;
        end
        %End TrackingProcess--------------------------------------------------
        
        %------------------------------------------------------------------
        %トラッカーの初期化関数
        function Target = InitializeTrackObj(app,Points,GrayFrameImage,Target)
            % ポイントトラッカーの初期化
            Target.xyPoints = Points.Location;
            Target.NumPts = size(Target.xyPoints,1);
            release(Target.Tracker);
            initialize(Target.Tracker, Target.xyPoints, GrayFrameImage);
            %１フレーム前のPointの位置を記憶させる.
            Target.OldPoints = Target.xyPoints;
            Target.BBoxPoints = bbox2points(Target.BBox(1, :));
        end
        %end InitializeTrackObjI-------------------------------------------
        
        %------------------------------------------------------------------
        %トラッカーのポリゴンフレームの座標を調整する。
        function [Xmin,Xmax,Ymin,Ymax,bboxPolygon] = fixReqPoint(app,bboxPolygon)
            
            bboxPolygon(1) = max(bboxPolygon(1),bboxPolygon(7));%X1
            bboxPolygon(2) = max(bboxPolygon(2),bboxPolygon(4));%Y1
            bboxPolygon(3) = max(bboxPolygon(3),bboxPolygon(5));%X2
            bboxPolygon(4) = max(bboxPolygon(2),bboxPolygon(4));%Y2
            bboxPolygon(5) = max(bboxPolygon(3),bboxPolygon(5));%X3
            bboxPolygon(6) = max(bboxPolygon(6),bboxPolygon(8));%Y3
            bboxPolygon(7) = max(bboxPolygon(1),bboxPolygon(7));%X4
            bboxPolygon(8) = max(bboxPolygon(6),bboxPolygon(8));%Y4
            
            Xmin  = round(max(bboxPolygon(1),bboxPolygon(7))) - app.XExpand ;
            Xmax = round(max(bboxPolygon(3),bboxPolygon(5))) + app.XExpand ;
            Ymin  = round(max(bboxPolygon(2),bboxPolygon(4))) - app.YExpand ;
            Ymax = round(max(bboxPolygon(6),bboxPolygon(8))) + app.YExpand ;
        end
        %------------------------------------------------------------------
        
        %------------------------------------------------------------------
        %オブジェクトトラッカーを更新する
        function [ReturnVideoFrame,ReturnTarget] = UpdateTrackObject(app,Target,VideoFrame)
            
           %グレースケール画像を生成
           GrayFrameImage = rgb2gray(VideoFrame);

           %グレーフレームから現在のトラックポイントを取得する
           [xyPoints, isFound] = step(Target.Tracker, GrayFrameImage);
           visiblePoints = xyPoints(isFound, :);
           oldInliers = Target.OldPoints(isFound, :);
           Target.NumPts = size(visiblePoints, 1);

           %ターゲットのトラックポイントが10以上なトラックを継続する。
           if Target.NumPts >= 10
                % 一つ前のフレームで作成したポイント位置
                %現在のフレームのポイント位置のズレから2次元の幾何学変換を推定
                [xform, inlierIdx] = estimateGeometricTransform2D(...
                    oldInliers, visiblePoints, 'similarity', 'MaxDistance', 4);
                visiblePoints = visiblePoints(inlierIdx, :);
    
                % 順方向アフィン変換でトラッキング領域を示すポリゴン座標を変換
                Target.BBoxPoints = transformPointsForward(xform, Target.BBoxPoints);
    
                % 特定した目領域を示す四角形の座標
                % 座標形式を[x1 y1 x2 y2 x3 y3 x4 y4]に変換
                
                bboxPolygon = reshape(Target.BBoxPoints', 1, []);
    
                %平行四辺形にならないように調整
                [Xmin,Xmax,Ymin,Ymax,bboxPolygon] = fixReqPoint(app,bboxPolygon);
                
                % ポリゴンを画面に重畳.
                %VideoFrame = insertShape(VideoFrame, 'Polygon', bboxPolygon, 'LineWidth', 3);
    
                % トラックポイントを描画.
                %videoFrame = insertMarker(videoFrame, visiblePoints, '+', 'Color', 'white');
    
                % ポイント位置を更新
                %EyeesPointの位置をメモリに記憶させる.
                Target.OldPoints =  visiblePoints;
                setPoints(Target.Tracker,Target.OldPoints);
                
                %錯視が有効ならばオブジェクトの位置のフレームを反転させる
                if strcmp(app.IllusionSwitch.Value,'On')
                    VideoFrame(Ymin:Ymax,Xmin:Xmax,:) = flipud(VideoFrame(Ymin:Ymax,Xmin:Xmax,:));
                end
           end
           ReturnVideoFrame = VideoFrame;
           ReturnTarget = Target;
        end
        %------------------------------------------------------------------
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %ウェブカメラリスト取得
            app.VideoSourceDropDown.Items = webcamlist;
            
            %カメラオブジェクト起動
            app.cam = webcam();

            %FPS設定
            FPS = 10;
         
            %プロパティ初期化
            app.runLoop = true;
            
            %目の領域特定関係のプロパティ
            app.Eyes.NumPts = 0;
            app.Eyes.BBoxPoionts = 0;
            app.Eyes.xyPoint = 0;
            app.Eyes.OldPoints = 0;
            app.Eyes.NumPts = 0;
            app.Eyes.BBox = 0;
      
            %口の領域特定関係のプロパティ
            app.Mouth.NumPts = 0;
            app.Mouth.BBoxPoints = 0;
            app.Mouth.xyPoint = 0;
            app.Mouth.OldPoints = 0;
            app.Mouth.NumPts = 0;
            app.Mouth.BBox = 0;
            
            
            app.XExpand = 20;
            app.YExpand = 10;
            
            app.EnableIllusion = false;
            app.VerticalFlip = false;

            
            % 学習済み物体認識オブジェクトを生成
            app.FaceDetector = vision.CascadeObjectDetector();
            app.Eyes.Detector = vision.CascadeObjectDetector('EyePairSmall', 'UseROI', true);
            app.Mouth.Detector = vision.CascadeObjectDetector('Mouth', 'UseROI', true);
            
            %追跡オブジェクト生成
            app.Eyes.Tracker = vision.PointTracker('MaxBidirectionalError', 2);
            app.Mouth.Tracker = vision.PointTracker('MaxBidirectionalError', 2);

            %タイマオブジェクト生成
            app.UpdateTimer = timer('Period', 1/FPS,...
                'ExecutionMode', 'fixedRate', ...
                'TasksToExecute', Inf, ... 
                'TimerFcn', @app.mytimer_func);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.UpdateTimer);
            delete(app.UpdateTimer);
            delete(app.cam);
            delete(app)
            
        end

        % Value changed function: PlayVideoButton
        function PlayVideoButtonValueChanged(app, event)
            value = app.PlayVideoButton.Value;
            
            if value
                %タイマ開始
                start(app.UpdateTimer);
            else
                %タイマ停止
                stop(app.UpdateTimer);
            end
        end

        % Value changed function: VideoSourceDropDown
        function VideoSourceDropDownValueChanged(app, event)
            value = app.VideoSourceDropDown.Value;
            %カメラオブジェクト削除
            delete(app.cam);
            %カメラオブジェクト起動
            app.cam = webcam(value);

        end

        % Button pushed function: ReDetectObjectsButton
        function ReDetectObjectsButtonPushed(app, event)
            app.Eyes.NumPts = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.VideoControlPanel = uipanel(app.UIFigure);
            app.VideoControlPanel.Title = 'Video Control';
            app.VideoSourceDropDownLabel = uilabel(app.VideoControlPanel);
            app.VideoSourceDropDownLabel.Text = 'VideoSource';
            app.VideoSourceDropDown = uidropdown(app.VideoControlPanel);
            app.VideoSourceDropDown.ValueChangedFcn = createCallbackFcn(app, @VideoSourceDropDownValueChanged, true);
            app.PlayVideoButton = uibutton(app.VideoControlPanel, 'state');
            app.PlayVideoButton.ValueChangedFcn = createCallbackFcn(app, @PlayVideoButtonValueChanged, true);
            app.PlayVideoButton.Text = 'Play Video';
            app.ReDetectObjectsButton = uibutton(app.VideoControlPanel, 'push');
            app.ReDetectObjectsButton.ButtonPushedFcn = createCallbackFcn(app, @ReDetectObjectsButtonPushed, true);
            app.ReDetectObjectsButton.Text = 'ReDetect Objects';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.IllusionStatusControlPanel = uipanel(app.UIFigure);
            app.IllusionStatusControlPanel.Title = 'Illusion Status Control';
            app.IllusionSwitchLabel = uilabel(app.IllusionStatusControlPanel);
            app.IllusionSwitchLabel.Text = 'Illusion';
            app.IllusionSwitch = uiswitch(app.IllusionStatusControlPanel, 'rocker');
            app.VerticalFlipSwitchLabel = uilabel(app.IllusionStatusControlPanel);
            app.VerticalFlipSwitchLabel.Text = 'Vertical Flip';
            app.VerticalFlipSwitch = uiswitch(app.IllusionStatusControlPanel, 'rocker');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThacherIllusionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end